classdef Lab6_Car_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        BuzzerButton      matlab.ui.control.Button
        SpeedSlider       matlab.ui.control.Slider
        SpeedSliderLabel  matlab.ui.control.Label
        Label             matlab.ui.control.Label
    end

    
     properties (Access = private)
        mqClient
        priorKey = ''
    end
    
    methods (Access = private)
        
        function myCallback(app, topic, message)
            fprintf("Recieved: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            
            if ~isempty(messageStruct.payload)
                messagePayload = messageStruct.payload;
            end

        end

        function sendMessage(app, messageType, messagePayload)
            messageStruct.type = messageType;
            if strcmp(messageType, "set_speeds")
                messageStruct.payload = messagePayload;
            end
            messageJson = jsonencode(messageStruct);

            write(app.mqClient, "me435/eckelbge/to_pi",messageJson)
            fprintf("Sent: %s\n",messageJson)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://mosquitto.csse.rose-hulman.edu");
            app.mqClient.subscribe("me435/eckelbge/#", "Callback", @app.myCallback)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return; % The key is being held down ignore this one
            end
            app.priorKey = key;

            speed = round(app.SpeedSlider.Value);

            if strcmp(key,"uparrow")
                app.sendMessage("set_speeds",[speed speed speed speed])
            end

            if strcmp(key,"downarrow")
                app.sendMessage("set_speeds",[-speed -speed -speed -speed])
            end

            if strcmp(key,"leftarrow")
                app.sendMessage("set_speeds",[-speed speed speed -speed])
            end

            if strcmp(key,"rightarrow")
                app.sendMessage("set_speeds",[speed -speed -speed speed])
            end

            if strcmp(key,"a")
                app.sendMessage("set_speeds",[-speed -speed speed speed])
            end

            if strcmp(key,"d")
                app.sendMessage("set_speeds",[speed speed -speed -speed])
            end

        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            app.priorKey = "";
            app.sendMessage("stop")
            
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            app.sendMessage("beep")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Click in the area below to use the keyboard.';
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Value = 40;
            app.BuzzerButton = uibutton(app.UIFigure, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab6_Car_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end